classdef steering_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DiagramTypeDropDown            matlab.ui.control.DropDown
        DiagramTypeDropDownLabel       matlab.ui.control.Label
        NumberofLinkagesDropDown       matlab.ui.control.DropDown
        NumberofLinkagesDropDownLabel  matlab.ui.control.Label
        WheelBaseEditField             matlab.ui.control.NumericEditField
        WheelBaseEditFieldLabel        matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        TrackWidthEditField            matlab.ui.control.NumericEditField
        TrackWidthEditFieldLabel       matlab.ui.control.Label
        TrailingArmsEditField          matlab.ui.control.NumericEditField
        TrailingArmsEditFieldLabel     matlab.ui.control.Label
        SteeringRackEditField          matlab.ui.control.NumericEditField
        SteeringRackEditFieldLabel     matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function UIAxesButtonDown(app, event)

        end

        % Callback function: RunButton, UIAxes
        function RunButtonPushed(app, event)
            four_linkage_model(app.TrailingArmsEditField.Value, ...
                app.TrailingArmsEditField.Value, ...
                app.TrackWidthEditField.Value, 0, ...
                app.SteeringRackEditField.Value, ...
                app.WheelBaseEditField.Value, app.UIAxes)
        end

        % Value changed function: WheelBaseEditField
        function WheelBaseEditFieldValueChanged(app, event)
            value = app.WheelBaseEditField.Value;
        end

        % Value changed function: TrailingArmsEditField
        function TrailingArmsEditFieldValueChanged(app, event)
            value = app.TrailingArmsEditField.Value;
        end

        % Value changed function: SteeringRackEditField
        function SteeringRackEditFieldValueChanged(app, event)
            value = app.SteeringRackEditField.Value;
        end

        % Value changed function: TrackWidthEditField
        function TrackWidthEditFieldValueChanged(app, event)
            value = app.TrackWidthEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Linkage Animation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.SteeringRackEditFieldLabel = uilabel(app.UIFigure);
            app.SteeringRackEditFieldLabel.Text = 'Steering Rack';
            app.SteeringRackEditField = uieditfield(app.UIFigure, 'numeric');
            app.SteeringRackEditField.ValueChangedFcn = createCallbackFcn(app, @SteeringRackEditFieldValueChanged, true);
            app.TrailingArmsEditFieldLabel = uilabel(app.UIFigure);
            app.TrailingArmsEditFieldLabel.Text = 'Trailing Arms';
            app.TrailingArmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrailingArmsEditField.ValueChangedFcn = createCallbackFcn(app, @TrailingArmsEditFieldValueChanged, true);
            app.TrackWidthEditFieldLabel = uilabel(app.UIFigure);
            app.TrackWidthEditFieldLabel.Text = 'Track Width';
            app.TrackWidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrackWidthEditField.ValueChangedFcn = createCallbackFcn(app, @TrackWidthEditFieldValueChanged, true);
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.WheelBaseEditFieldLabel = uilabel(app.UIFigure);
            app.WheelBaseEditFieldLabel.Text = 'Wheel Base';
            app.WheelBaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.WheelBaseEditField.ValueChangedFcn = createCallbackFcn(app, @WheelBaseEditFieldValueChanged, true);
            app.NumberofLinkagesDropDownLabel = uilabel(app.UIFigure);
            app.NumberofLinkagesDropDownLabel.Text = 'Number of Linkages';
            app.NumberofLinkagesDropDown = uidropdown(app.UIFigure);
            app.NumberofLinkagesDropDown.Items = {'4 bar linkage', '6 bar linkage'};
            app.NumberofLinkagesDropDown.Value = '4 bar linkage';
            app.DiagramTypeDropDownLabel = uilabel(app.UIFigure);
            app.DiagramTypeDropDownLabel.Text = 'Diagram Type';
            app.DiagramTypeDropDown = uidropdown(app.UIFigure);
            app.DiagramTypeDropDown.Items = {'Linkage Diagram', 'Steering Diagram'};
            app.DiagramTypeDropDown.Value = 'Linkage Diagram';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = steering_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end